classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FITNESSappUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LogowanieTab                  matlab.ui.container.Tab
        WczytajButton                 matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        UITable                       matlab.ui.control.Table
        Button_2                      matlab.ui.control.Button
        ZapiszButton                  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        NickEditField                 matlab.ui.control.EditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        MczyznaButton                 matlab.ui.control.RadioButton
        KobietaButton                 matlab.ui.control.RadioButton
        NickEditFieldLabel            matlab.ui.control.Label
        NazwiskoEditField             matlab.ui.control.EditField
        NazwiskoEditFieldLabel        matlab.ui.control.Label
        ImiEditField                  matlab.ui.control.EditField
        ImiEditFieldLabel             matlab.ui.control.Label
        PrzedrozpoczciemzakontoLabel  matlab.ui.control.Label
        WitajLabel                    matlab.ui.control.Label
        BMITab                        matlab.ui.container.Tab
        Tab3                          matlab.ui.container.Tab
        AlicjaTab                     matlab.ui.container.Tab
        PokasiownieButton             matlab.ui.control.Button
        miastoButtonGroup             matlab.ui.container.ButtonGroup
        KrakwKazimierzButton          matlab.ui.control.RadioButton
        WarszawaBielanyButton         matlab.ui.control.RadioButton
        WarszawardmiecieButton        matlab.ui.control.RadioButton
        UITable2                      matlab.ui.control.Table
        RankingTab                    matlab.ui.container.Tab
        UITable3                      matlab.ui.control.Table
        Label_3                       matlab.ui.control.Label
        PokawynikiButton              matlab.ui.control.Button
        ilKg                          matlab.ui.control.NumericEditField
        IlekilogramwudaoCisischudnLabel  matlab.ui.control.Label
        Lamp_R                        matlab.ui.control.Lamp
        PodajswjnickEditField         matlab.ui.control.EditField
        PodajswjnickEditFieldLabel    matlab.ui.control.Label
        RANKINGUYTKOWNIKWLabel        matlab.ui.control.Label
        SzlakiroweroweTab             matlab.ui.container.Tab
        MiastoButtonGroup             matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.RadioButton
        Button2                       matlab.ui.control.RadioButton
        WarszawaButton                matlab.ui.control.RadioButton
        PoszukiwanieszlakuButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        t %mozliwosc uzywania tabeli w innych przyciskach ale teraz wszedzie dodajemy app.t
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick'); sprintf('Płeć K/M')};
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global t
            imie = app.ImiEditField.Value
            nazw = app.NazwiskoEditField.Value
            nick = string(app.NickEditField.Value)
            
            %petla wybierajaca plec
            if app.KobietaButton.Value ==1 %warunek wybierajacy ktory rodzaj laty 
                plec = 'K';
            elseif app.MczyznaButton.Value ==1
                plec = 'M';
            end
            nr = {imie nazw nick plec}; %new row
            i=1
            dlugosc = numel(app.t(:,3))
            
            %while i <= dlugosc
            %    if nick == app.t(i,3)
            %        app.Lamp.Color = 'r';
            %        break
            %    else 
            %        app.Lamp.Color = 'g';
            %        
            %        i=i+1 
            %        continue
            %    end
            %end
            
%ta petla stwierdza czy nick sie powtarza
            if dlugosc ==0  %tu gdy mamy pusta tabelke na poczatku
                odp = 0;
                app.Lamp.Color = 'g';
            else
                for id=1:dlugosc
                    if nick == app.t{id,3} %cell array 
                        app.Lamp.Color = 'r';
                        odp = 1
                        break
                    else
                        app.Lamp.Color = 'g';
                        odp = 0
                        continue                      
                   end
                end
            end           
%ta petla na podstawie poprzedniej decyduje czy dodajemy nowy wiersz czy nie 
            if odp == 1     %jesli odp = nie to nie robimy nic
                app.Label.Text = 'Taki Nick już istnieje.';
                app.Label_2.Text = 'Użyj innego.';
                return
            else           %w innym wypadku dodajemy to tablicy new row
                app.Label.Text = 'Wybrany przez ciebie Nick jest wolny.' ;
                app.Label_2.Text = 'Udało się!';
                app.UITable.Data = [app.t ; nr];
                app.t = app.UITable.Data;
            end
            tabela = readtable('dane.xlsx','Sheet',1);
            newtable = table()
            newtable.Imie = [imie]
            newtable.Nazwisko = [nazw]
            newtable.Nick = [nick]
            newtable.P_e_ = [plec]
            tabela = [tabela; newtable]
            writetable(tabela,'dane.xlsx')
        ala=21
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick')};
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: PokasiownieButton
        function PokasiownieButtonPushed(app, event)
             %Warszawa = [52.2297700, 21.0117800];
            %Gdansk = [54.3520500, 18.6463700];
            %Krakow = [50.0614300, 19.9365800];
            
            silownia1= [52.25723, 20.98457351, "ZDROFIT", "al. Jana Pawła II 82, CH ARKADIA"]  ;
            silownia2= [52.232879, 20.999556, "ZDROFIT", "ul. RONDO ONZ 1"] ;
            silownia3= [52.2941895, 20.9303168, "ZDROFIT", "ul. Zgrupowania AK 'Kampinos' 15, GALERIA MŁOCINY"] ;
            silownia4= [52.2912362, 20.9363057, "ZDROFIT" "ul. Przy Agorze 22"];
            silownia5=["Siłownia Kulturystyczna CHAMPION", "ul. Księdza Augustyna Kordeckiego 7"];
            silownia6=["Atlantic Squash & Fitness","ul. Stradomska 15"];
            silownia7=["fitLady", "ul. Marii Konopnickiej 5"];
            silownia8=["EMS 2020", "ul. Antoniego Józefa Madalińskiego 11/3"];
            silownia9=["ZDROFIT", "al. Grunwaldzka 82, CH MANHATTAN"];
            silownia10=["Tonezone", "ul. Jana Kilińskiego 4"];
            silownia11=["CityFit", "Targ Sienny 7"];
            silownia12=["Yoga Compass", "ul. Wojciecha Bogusławskiego 1"];
            
            
            WarszawaBielany=[silownia3(3), silownia3(4); silownia4(3), silownia4(4)];
            WarszawaSrodmiescie=[silownia1(3), silownia1(4); silownia2(3), silownia2(4)];
            KrakowKazimierz=[silownia5(1), silownia5(2); silownia6(1), silownia6(2)];
            KrakowDebniki=[silownia7(1), silownia7(2); silownia8(1), silownia8(2)];
            GdanskWrzeszcz=[silownia9(1), silownia9(2); silownia10(1), silownia10(2)];
            GdanskSrodmiescie=[silownia11(1), silownia11(2); silownia12(1), silownia12(2)];
            
%             if app.GdaskWrzeszczButton.Value == 1
%                 tabela=table(GdanskWrzeszcz);
%             elseif app.GdaskrdmiecieButton.Value == 1
%                 tabela=table(GdanskSrodmiescie);
            if app.WarszawardmiecieButton.Value == 1
                tabela=table(WarszawaSrodmiescie);
            elseif app.KrakwKazimierzButton.Value == 1
                tabela=table(KrakowKazimierz);
            elseif app.WarszawaBielanyButton.Value == 1
                tabela=table(WarszawaBielany);
%             elseif app.KrakwDbnikiButton.Value == 1
%                 tabela=table(KrakowDebniki);
            end
            
            app.UITable2.Data=tabela;
            app.UITable2.Visible='on';
            app.miastoButtonGroup.Scrollable='on';
            
        end

        % Button pushed function: PokawynikiButton
        function PokawynikiButtonPushed(app, event)
            global t
            nick1 = string(app.PodajswjnickEditField.Value)
            ilKg = app.ilKg.Value
            dlugosc = numel(app.t(:,3))
            
            for id=1:dlugosc
                    if nick1 == app.t{id,3} 
                       app.Lamp_R.Color = 'g';
                       warunek_T_N = 0
                       break
                    else
                       app.Lamp_R.Color = 'r';
                       warunek_T_N = 1
                       continue                      
                   end
            end           
            if warunek_T_N == 0
                tabelaR = readtable('ranking.xlsx','Sheet',1);
                newtableR = table()
                newtableR.Nick = [nick1]
                newtableR.IloscKilogramow = [ilKg]
                tabelaR = [tabelaR; newtableR] 
                tabelasort = sortrows(tabelaR,{'IloscKilogramow'},{'descend'})
                app.UITable3.Data=tabelasort;
                app.UITable3.Visible='on';
                writetable(tabelaR,'ranking.xlsx')
            else 
                app.Label_3.Text = ['Nie ma takiego użytkownika. ' ...
                    'Załóż konto.'];
            end
            

        end

        % Button pushed function: PoszukiwanieszlakuButton
        function PoszukiwanieszlakuButtonPushed(app, event)
       if app.WarszawaButton.Value == 1
            tracks = gpxread('debno-sarbinowo-dabroszyn-witnica.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
       end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FITNESSappUIFigure = uifigure('Visible', 'off');
            app.FITNESSappUIFigure.Name = 'FITNESSapp';
            app.TabGroup = uitabgroup(app.FITNESSappUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LogowanieTab = uitab(app.TabGroup);
            app.LogowanieTab.Title = 'Logowanie';
            app.WitajLabel = uilabel(app.LogowanieTab);
            app.WitajLabel.Text = 'Witaj!  ';
            app.PrzedrozpoczciemzakontoLabel = uilabel(app.LogowanieTab);
            app.PrzedrozpoczciemzakontoLabel.Text = 'Przed rozpoczęciem - załóż konto:';
            app.Panel = uipanel(app.LogowanieTab);
            app.ImiEditFieldLabel = uilabel(app.Panel);
            app.ImiEditFieldLabel.Text = 'Imię';
            app.ImiEditField = uieditfield(app.Panel, 'text');
            app.NazwiskoEditFieldLabel = uilabel(app.Panel);
            app.NazwiskoEditFieldLabel.Text = 'Nazwisko';
            app.NazwiskoEditField = uieditfield(app.Panel, 'text');
            app.NickEditFieldLabel = uilabel(app.Panel);
            app.NickEditFieldLabel.Text = 'Nick';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KobietaButton = uiradiobutton(app.ButtonGroup);
            app.KobietaButton.Text = 'Kobieta';
            app.KobietaButton.Value = true;
            app.MczyznaButton = uiradiobutton(app.ButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.NickEditField = uieditfield(app.Panel, 'text');
            app.ZapiszButton = uibutton(app.LogowanieTab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'zapisz.png';
            app.ZapiszButton.Text = 'Zapisz';
            app.Button_2 = uibutton(app.LogowanieTab, 'push');
            app.Button_2.Icon = 'LOGO.jpg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.UITable = uitable(app.LogowanieTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.LogowanieTab);
            app.Button = uibutton(app.LogowanieTab, 'push');
            app.Button.Icon = 'LOGO.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Label_2 = uilabel(app.LogowanieTab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.LogowanieTab);
            app.Label.Text = '';
            app.WczytajButton = uibutton(app.LogowanieTab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Visible = 'off';
            app.WczytajButton.Text = 'Wczytaj';
            app.BMITab = uitab(app.TabGroup);
            app.BMITab.Title = 'BMI';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AlicjaTab = uitab(app.TabGroup);
            app.AlicjaTab.Title = 'Alicja';
            app.UITable2 = uitable(app.AlicjaTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.miastoButtonGroup = uibuttongroup(app.AlicjaTab);
            app.miastoButtonGroup.Title = 'miasto';
            app.WarszawardmiecieButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawardmiecieButton.Text = 'Warszawa Śródmieście';
            app.WarszawardmiecieButton.Value = true;
            app.WarszawaBielanyButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawaBielanyButton.Text = 'Warszawa Bielany';
            app.KrakwKazimierzButton = uiradiobutton(app.miastoButtonGroup);
            app.KrakwKazimierzButton.Text = 'Kraków Kazimierz';
            app.PokasiownieButton = uibutton(app.AlicjaTab, 'push');
            app.PokasiownieButton.ButtonPushedFcn = createCallbackFcn(app, @PokasiownieButtonPushed, true);
            app.PokasiownieButton.Text = 'Pokaż siłownie';
            app.RankingTab = uitab(app.TabGroup);
            app.RankingTab.Title = 'Ranking';
            app.RANKINGUYTKOWNIKWLabel = uilabel(app.RankingTab);
            app.RANKINGUYTKOWNIKWLabel.Text = 'RANKING UŻYTKOWNIKÓW';
            app.PodajswjnickEditFieldLabel = uilabel(app.RankingTab);
            app.PodajswjnickEditFieldLabel.Text = 'Podaj swój nick:';
            app.PodajswjnickEditField = uieditfield(app.RankingTab, 'text');
            app.Lamp_R = uilamp(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel = uilabel(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel.Text = 'Ile kilogramów udało ci się schudnąć?';
            app.ilKg = uieditfield(app.RankingTab, 'numeric');
            app.PokawynikiButton = uibutton(app.RankingTab, 'push');
            app.PokawynikiButton.ButtonPushedFcn = createCallbackFcn(app, @PokawynikiButtonPushed, true);
            app.PokawynikiButton.Text = 'Pokaż wyniki';
            app.Label_3 = uilabel(app.RankingTab);
            app.Label_3.Text = '';
            app.UITable3 = uitable(app.RankingTab);
            app.UITable3.ColumnName = {'Nick'; 'IloscKilogramow'};
            app.UITable3.RowName = {};
            app.SzlakiroweroweTab = uitab(app.TabGroup);
            app.SzlakiroweroweTab.Title = 'Szlaki rowerowe';
            app.PoszukiwanieszlakuButton = uibutton(app.SzlakiroweroweTab, 'push');
            app.PoszukiwanieszlakuButton.ButtonPushedFcn = createCallbackFcn(app, @PoszukiwanieszlakuButtonPushed, true);
            app.PoszukiwanieszlakuButton.Text = 'Poszukiwanie szlaku';
            app.MiastoButtonGroup = uibuttongroup(app.SzlakiroweroweTab);
            app.MiastoButtonGroup.Title = 'Miasto';
            app.WarszawaButton = uiradiobutton(app.MiastoButtonGroup);
            app.WarszawaButton.Text = 'Warszawa';
            app.WarszawaButton.Value = true;
            app.Button2 = uiradiobutton(app.MiastoButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.MiastoButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.FITNESSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FITNESSappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FITNESSappUIFigure)
        end
    end
end